classdef BehaviorAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BehavioranalysisLabel           matlab.ui.control.Label
        BehavioralTestsPanel            matlab.ui.container.Panel
        resultsButton                   matlab.ui.control.Button
        typetestDropDown                matlab.ui.control.DropDown
        typetestDropDownLabel           matlab.ui.control.Label
        loadconverteddataButton         matlab.ui.control.Button
        loaddatatoconvertButton         matlab.ui.control.Button
        InfraRedPanel                   matlab.ui.container.Panel
        loaddataButton                  matlab.ui.control.Button
        baselinedaysEditField           matlab.ui.control.NumericEditField
        baselinedaysLabel               matlab.ui.control.Label
        baselinedaysEditField_2         matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        SaveplotButton                  matlab.ui.control.Button
        MorePlotsButton                 matlab.ui.control.Button
        PlotActivityButton              matlab.ui.control.Button
        lightcyclecloset2DropDown       matlab.ui.control.DropDown
        lightcyclecloset2DropDownLabel  matlab.ui.control.Label
        lightcyclecloset1DropDown       matlab.ui.control.DropDown
        lightcyclecloset1DropDownLabel  matlab.ui.control.Label
        choosepathPanel                 matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        clearButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        path
        DATA % the rau data
        names
        Ta%type of light cycle for closet a
        beginTa%nuber days of baseline in closet a
        Tb%type of light cycle for closet b
        beginTb%nuber days of baseline in closet b
        numHours % number of 48-h periods (number of raws in actogram) or 24-h periods (number of raws in actogram)


        newdata
        test_type
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            [~,temppath] = uigetfile('*.csv',"select csv file",app.path);  %open the dialog box
            %datafile = fullfile(path, file); %path to the selected file
            if ~isempty(temppath)
                app.names=getFileList(temppath,'counts_',0,'anywhere');  % get names of data files
                data=[];
                for i=1:length(app.names)
                    data=[data;readtimetable(num2str(app.names{i}),'ReadVariableNames',0)];
                end
                %app.DATA=readtimetable(num2str(app.names{1}),'ReadVariableNames',0);
                app.DATA=timetable2table(data);
                msgbox('data was downloaded',"Success");
            else
                msgbox('data was not downloaded',"Error");
            end
        end

        % Value changed function: lightcyclecloset1DropDown
        function lightcyclecloset1DropDownValueChanged(app, event)
            value = app.lightcyclecloset1DropDown.Value;
            app.Ta=value;
            if app.Ta ~= "T24"
                app.baselinedaysEditField.Visible="on";
            end
        end

        % Value changed function: lightcyclecloset2DropDown
        function lightcyclecloset2DropDownValueChanged(app, event)
            value = app.lightcyclecloset2DropDown.Value;
            app.Tb=value;
            if app.Tb ~= "24"
                app.baselinedaysEditField_2.Visible="on";
            end
        end

        % Button pushed function: PlotActivityButton
        function PlotActivityButtonPushed(app, event)
            switch app.numHours
                case "24 hours plot"
                    PlotTaVSTbn24(app.DATA,app.Ta,app.Tb);
                case "48 hours plot"
                    PlotTaVSTbn48(app.data,app.Ta,app.Tb);
            end

            
        end

        % Button pushed function: SaveplotButton
        function SaveplotButtonPushed(app, event)
            numFigs=2;
            for i=1:numFigs
                exportgraphics(figure(i), 'output.pdf', 'Append', true);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            clear 
            clc
        end

        % Button pushed function: loaddatatoconvertButton
        function loaddatatoconvertButtonPushed(app, event)
            [file,temppath] = uigetfile('*.csv',"select csv file",app.path);  %open the dialog box
            %datafile = fullfile(path, file); %path to the selected file
            if ~isempty(temppath)
                app.newdata = converter(temppath,file);
                msgbox('data was downloaded',"Success");
            else
                msgbox('data was not downloaded',"Error");
            end
        end

        % Value changed function: typetestDropDown
        function typetestDropDownValueChanged(app, event)
            value = app.typetestDropDown.Value;
            app.test_type=value;
        end

        % Button pushed function: resultsButton
        function resultsButtonPushed(app, event)
            switch app.test_type
                case 'open field test'
                    OF_Plot(app.newdata);
                case 'EPM test'
                    EPM_Plot(app.newdata);
                case 'sugar preference test'
                    SPT_Plot(app.newdata);
                case 'SPT baseline'
                    SPT_BaseLine_Plot(app.newdata);
                case 'forced swim test'
                    FST_Plot(app.newdata);
                case 'All tests'
                    All_Plots(app.newdata);
            end
        end

        % Button pushed function: MorePlotsButton
        function MorePlotsButtonPushed(app, event)
            EstimateActivity(app.dataT);
            differencesInActivity(app.dataT);
        end

        % Button pushed function: loadconverteddataButton
        function loadconverteddataButtonPushed(app, event)
            [file,temppath] = uigetfile('*.mat',"select mat file",app.path);  %open the dialog box
            if ~isempty(temppath)
                load(fullfile(temppath, file),'newdata');
                app.newdata=newdata;
                msgbox('data was downloaded',"Success");
            else
                msgbox('data was not downloaded',"Error");
            end
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.path = uigetdir();  %open the dialog box
            app.EditField.Value=app.path;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.numHours=value;
        end

        % Value changed function: baselinedaysEditField
        function baselinedaysEditFieldValueChanged(app, event)
            value = app.baselinedaysEditField.Value;
            app.beginTa=value;
        end

        % Value changed function: baselinedaysEditField_2
        function baselinedaysEditField_2ValueChanged(app, event)
            value = app.baselinedaysEditField_2.Value;
            app.beginTb=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.choosepathPanel = uipanel(app.UIFigure);
            app.choosepathPanel.Title = 'choose path';
            app.BrowseButton = uibutton(app.choosepathPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EditField = uieditfield(app.choosepathPanel, 'text');
            app.InfraRedPanel = uipanel(app.UIFigure);
            app.InfraRedPanel.Title = 'Infra Red';
            app.lightcyclecloset1DropDownLabel = uilabel(app.InfraRedPanel);
            app.lightcyclecloset1DropDownLabel.Text = 'light cycle closet 1';
            app.lightcyclecloset1DropDown = uidropdown(app.InfraRedPanel);
            app.lightcyclecloset1DropDown.Items = {'T7', 'T6', 'T24', 'CT13', 'CT16', 'CT18', 'CT20'};
            app.lightcyclecloset1DropDown.ValueChangedFcn = createCallbackFcn(app, @lightcyclecloset1DropDownValueChanged, true);
            app.lightcyclecloset1DropDown.Value = 'T7';
            app.lightcyclecloset2DropDownLabel = uilabel(app.InfraRedPanel);
            app.lightcyclecloset2DropDownLabel.Text = 'light cycle closet 2';
            app.lightcyclecloset2DropDown = uidropdown(app.InfraRedPanel);
            app.lightcyclecloset2DropDown.Items = {'T7', 'T6', 'T24', 'CT13', 'CT16', 'CT18', 'CT20'};
            app.lightcyclecloset2DropDown.ValueChangedFcn = createCallbackFcn(app, @lightcyclecloset2DropDownValueChanged, true);
            app.lightcyclecloset2DropDown.Value = 'T7';
            app.PlotActivityButton = uibutton(app.InfraRedPanel, 'push');
            app.PlotActivityButton.ButtonPushedFcn = createCallbackFcn(app, @PlotActivityButtonPushed, true);
            app.PlotActivityButton.Text = 'Plot Activity';
            app.MorePlotsButton = uibutton(app.InfraRedPanel, 'push');
            app.MorePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @MorePlotsButtonPushed, true);
            app.MorePlotsButton.Text = 'More Plots';
            app.SaveplotButton = uibutton(app.InfraRedPanel, 'push');
            app.SaveplotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotButtonPushed, true);
            app.SaveplotButton.Visible = 'off';
            app.SaveplotButton.Text = 'Save plot';
            app.DropDown = uidropdown(app.InfraRedPanel);
            app.DropDown.Items = {'choose type hour', '24 hours plot', '48 hours plot'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'choose type hour';
            app.baselinedaysEditField_2 = uieditfield(app.InfraRedPanel, 'numeric');
            app.baselinedaysEditField_2.ValueChangedFcn = createCallbackFcn(app, @baselinedaysEditField_2ValueChanged, true);
            app.baselinedaysEditField_2.Visible = 'off';
            app.baselinedaysLabel = uilabel(app.InfraRedPanel);
            app.baselinedaysLabel.Text = {'baseline '; 'days'};
            app.baselinedaysEditField = uieditfield(app.InfraRedPanel, 'numeric');
            app.baselinedaysEditField.ValueChangedFcn = createCallbackFcn(app, @baselinedaysEditFieldValueChanged, true);
            app.baselinedaysEditField.Visible = 'off';
            app.loaddataButton = uibutton(app.InfraRedPanel, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.BehavioralTestsPanel = uipanel(app.UIFigure);
            app.BehavioralTestsPanel.Title = 'Behavioral Tests';
            app.loaddatatoconvertButton = uibutton(app.BehavioralTestsPanel, 'push');
            app.loaddatatoconvertButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatatoconvertButtonPushed, true);
            app.loaddatatoconvertButton.Text = 'load data to convert';
            app.loadconverteddataButton = uibutton(app.BehavioralTestsPanel, 'push');
            app.loadconverteddataButton.ButtonPushedFcn = createCallbackFcn(app, @loadconverteddataButtonPushed, true);
            app.loadconverteddataButton.Text = 'load converted data';
            app.typetestDropDownLabel = uilabel(app.BehavioralTestsPanel);
            app.typetestDropDownLabel.Text = 'type test';
            app.typetestDropDown = uidropdown(app.BehavioralTestsPanel);
            app.typetestDropDown.Items = {'choose test type ', 'open field test', 'EPM test', 'sugar preference test', 'SPT baseline', 'forced swim test', 'All tests'};
            app.typetestDropDown.ValueChangedFcn = createCallbackFcn(app, @typetestDropDownValueChanged, true);
            app.typetestDropDown.Value = 'choose test type ';
            app.resultsButton = uibutton(app.BehavioralTestsPanel, 'push');
            app.resultsButton.ButtonPushedFcn = createCallbackFcn(app, @resultsButtonPushed, true);
            app.resultsButton.Text = 'results';
            app.BehavioranalysisLabel = uilabel(app.UIFigure);
            app.BehavioranalysisLabel.Text = 'Behavior analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end